classdef Counting_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SelectionTab                  matlab.ui.container.Tab
        BrowseButton                  matlab.ui.control.Button
        RGBFilterButton_2             matlab.ui.control.Button
        CountingButton                matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        RecognitionButton             matlab.ui.control.Button
        AmountoffishesEditFieldLabel  matlab.ui.control.Label
        AmountoffishesEditField       matlab.ui.control.NumericEditField
        TypeofFishEditFieldLabel      matlab.ui.control.Label
        TypeofFishEditField           matlab.ui.control.EditField
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_6                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        I;
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseButton, UIAxes
        function BrowseButtonPushed(app, event)
            [filename,filepath] =uigetfile({'*.*';'*.jpg*';'*.png*';'*.bmp*'}, 'Search Image');
            fullname = [filepath filename];
            app.I = imread(fullname);
            imshow(app.I,"Parent",app.UIAxes);
            title(app.UIAxes, {'Original'; ''})
        end

        % Button pushed function: RGBFilterButton_2
        function RGBFilterButton_2Pushed(app, event)
            % Read in original RGB image.
            rgbImage = app.I;
            % Extract color channels.
            redChannel = rgbImage(:,:,1); % Red channel
            greenChannel = rgbImage(:,:,2); % Green channel
            blueChannel = rgbImage(:,:,3); % Blue channel
            % Create an all black channel.
            allBlack = zeros(size(rgbImage, 1), size(rgbImage, 2), 'uint8');
            % Create color versions of the individual color channels.
            just_red = cat(3, redChannel, allBlack, allBlack);
            just_green = cat(3, allBlack, greenChannel, allBlack);
            just_blue = cat(3, allBlack, allBlack, blueChannel);       
            imshow(just_red,"Parent",app.UIAxes_2);
            imshow(just_green,"Parent",app.UIAxes_6);
            imshow(just_blue,"Parent",app.UIAxes_4);
            title(app.UIAxes_2, {'Red Plane'; ''})
            title(app.UIAxes_6, {'Green Plane'; ''})
            title(app.UIAxes_4, 'Blue Plane')
        end

        % Callback function: AmountoffishesEditField, CountingButton
        function CountingButtonPushed(app, event)
            G = rgb2gray(app.I);
            [counts_G,binsLocation_G]= imhist(G);
            image_mean_G = sum(counts_G.*binsLocation_G)/sum(counts_G)
            bw = G>image_mean_G;
            se = strel('disk',2);
            Iopenned = imopen(bw,se);
            Iclosed = imclose(Iopenned,se);
            [L,n]=bwlabel(~Iclosed,4);
            r=regionprops(L,'BoundingBox');
            imshow(app.I,'Parent',app.UIAxes_5);
            for x = 1:n
            rectangle('Position',r(x).BoundingBox,"EdgeColor","r","LineWidth",1,'Parent',app.UIAxes_5);
            app.AmountoffishesEditField.Value = double(n);
            title(app.UIAxes_5, 'Counting')
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_2,'reset');
            cla(app.UIAxes_4,'reset');
            cla(app.UIAxes_6,'reset');
            cla(app.UIAxes_5,'reset');
            title(app.UIAxes, {'Original'; ''})
            title(app.UIAxes_2, {'Red Plane'; ''})
            title(app.UIAxes_6, {'Green Plane'; ''})
            title(app.UIAxes_4, 'Blue Plane')
            title(app.UIAxes_5, 'Counting')
            app.AmountoffishesEditField.Value = 0;
            app.TypeofFishEditField.Value = '-';    
        end

        % Button pushed function: RecognitionButton
        function RecognitionButtonPushed(app, event)
            net = googlenet;
            inputSize = net.Layers(1).InputSize;
            app.I = imresize(app.I,inputSize(1:2));
            label = classify(net,app.I);
            Text = (string(label))
            app.TypeofFishEditField.Value = Text;   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.BrowseButton = uibutton(app.SelectionTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RGBFilterButton_2 = uibutton(app.SelectionTab, 'push');
            app.RGBFilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @RGBFilterButton_2Pushed, true);
            app.RGBFilterButton_2.Text = 'RGB Filter';
            app.CountingButton = uibutton(app.SelectionTab, 'push');
            app.CountingButton.ButtonPushedFcn = createCallbackFcn(app, @CountingButtonPushed, true);
            app.CountingButton.Text = 'Counting';
            app.ResetButton = uibutton(app.SelectionTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RecognitionButton = uibutton(app.SelectionTab, 'push');
            app.RecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @RecognitionButtonPushed, true);
            app.RecognitionButton.Text = 'Recognition';
            app.AmountoffishesEditFieldLabel = uilabel(app.UIFigure);
            app.AmountoffishesEditFieldLabel.Text = 'Amount of fishes';
            app.AmountoffishesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountoffishesEditField.ValueChangedFcn = createCallbackFcn(app, @CountingButtonPushed, true);
            app.TypeofFishEditFieldLabel = uilabel(app.UIFigure);
            app.TypeofFishEditFieldLabel.Text = 'Type of Fish';
            app.TypeofFishEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Original'; ''})
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, {'Red Plane'; ''})
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Blue Plane')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Counting')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, {'Green Plane'; ''})
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Counting_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end