classdef Counting_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        BrowseTab                    matlab.ui.container.Tab
        BrowseButton                 matlab.ui.control.Button
        RGBFilterTab                 matlab.ui.container.Tab
        RGBFilterButton              matlab.ui.control.Button
        CountingTab                  matlab.ui.container.Tab
        CountingButton               matlab.ui.control.Button
        NumberoffishesTextAreaLabel  matlab.ui.control.Label
        NumberoffishesTextArea       matlab.ui.control.TextArea
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Filter(app)
            % Read in original RGB image.
            rgbImage = I;
            % Extract color channels.
            redChannel = rgbImage(:,:,1); % Red channel
            greenChannel = rgbImage(:,:,2); % Green channel
            blueChannel = rgbImage(:,:,3); % Blue channel
            % Create an all black channel.
            allBlack = zeros(size(rgbImage, 1), size(rgbImage, 2), 'uint8');
            % Create color versions of the individual color channels.
            just_red = cat(3, redChannel, allBlack, allBlack);
            just_green = cat(3, allBlack, greenChannel, allBlack);
            just_blue = cat(3, allBlack, allBlack, blueChannel);
            % Recombine the individual color channels to create the original RGB image again.
            recombinedRGBImage = cat(3, redChannel, greenChannel, blueChannel);      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RGBFilterButton
        function RGBFilterButtonPushed(app, event)
            Filter(I);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            I = imread();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BrowseTab = uitab(app.TabGroup);
            app.BrowseTab.Title = 'Browse';
            app.BrowseButton = uibutton(app.BrowseTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RGBFilterTab = uitab(app.TabGroup);
            app.RGBFilterTab.Title = 'RGB Filter';
            app.RGBFilterButton = uibutton(app.RGBFilterTab, 'push');
            app.RGBFilterButton.ButtonPushedFcn = createCallbackFcn(app, @RGBFilterButtonPushed, true);
            app.RGBFilterButton.Text = 'RGB Filter';
            app.CountingTab = uitab(app.TabGroup);
            app.CountingTab.Title = 'Counting';
            app.CountingButton = uibutton(app.CountingTab, 'push');
            app.CountingButton.Text = 'Counting';
            app.NumberoffishesTextAreaLabel = uilabel(app.UIFigure);
            app.NumberoffishesTextAreaLabel.Text = 'Number of fishes';
            app.NumberoffishesTextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Counting_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end